classdef FIR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FIRFilterDesignerusingGALabel  matlab.ui.control.Label
        FilterOrderEditField           matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel      matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        GenerationLabel                matlab.ui.control.Label
        CutoffSlider                   matlab.ui.control.RangeSlider
        CuttoffSliderLabel             matlab.ui.control.Label
        PassBandSlider                 matlab.ui.control.RangeSlider
        PassBandSliderLabel            matlab.ui.control.Label
        BestScoreLabel                 matlab.ui.control.Label
        GeneratePlotButton             matlab.ui.control.Button
        FilterTypeDropDown             matlab.ui.control.DropDown
        FilterTypeDropDownLabel        matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_1                       matlab.ui.control.UIAxes
    end

    methods (Access = private)
    function [coeffs, fval] = designFIRFilter(app)
        % Genetic Algorithm for FIR filter design

        % Retrieve inputs from the UI
        filterType = app.FilterTypeDropDown.Value;
        passBand = app.PassBandSlider.Value; % [low, high] range for pass band
        cutoff = app.CutoffSlider.Value;     % [low, high] range for stop bands (if applicable)

        % Objective function for GA
        function error = filterObjective(coeffs)
            [H, W] = freqz(coeffs, 1, 1024); % Frequency response of filter
            desiredResponse = getDesiredResponse(app, W/pi, filterType, passBand, cutoff);
            error = sum((abs(H) - desiredResponse).^2); % Minimize squared error
            
            % Add a small penalty for high ripples
            ripple_penalty = 0.1 * sum(diff(abs(H)).^2);
            error = error + ripple_penalty;
        end

        % GA parameters
        filterOrder = app.FilterOrderEditField.Value; % Fixed order (adjust if needed)
        lb = -1 * ones(1, filterOrder + 1); % Lower bounds
        ub = 1 * ones(1, filterOrder + 1);  % Upper bounds

        % Define options with OutputFcn
        options = optimoptions('ga', ...
            'MaxGenerations', 100, ...
            'Display', 'off', ...
            'OutputFcn', @(options,state,flag)gaOutputFunction(app, options, state, flag), ...
            'PopulationSize', 100, ...
            'CrossoverFraction', 0.8, ...
            'EliteCount', 2);

        % Run GA optimization
        [coeffs, fval] = ga(@filterObjective, filterOrder + 1, [], [], [], [], lb, ub, [], options);
    end

    function desiredResponse = getDesiredResponse(~, W, filterType, passBand, cutoff)
        % Generate the desired response based on filter type
        desiredResponse = zeros(size(W));
        transition_width = 0.01; % 5% transition width
        
        switch filterType
    case 'Low Pass Filter'
        % Main passband
        desiredResponse(W <= passBand(1)) = 1;
        % Transition band
        trans_idx = W > passBand(1) & W <= cutoff(1);
        desiredResponse(trans_idx) = 0.5 * (1 + cos(pi * (W(trans_idx) - passBand(1)) / (cutoff(1) - passBand(1))));
        % Stopband
        desiredResponse(W > cutoff(1)) = 0;

    case 'High Pass Filter'
        % Main passband
        desiredResponse(W >= passBand(1)) = 1;
        % Transition band
        trans_idx = W >= cutoff(1) & W < passBand(1);
        desiredResponse(trans_idx) = 0.5 * (1 - cos(pi * (W(trans_idx) - cutoff(1)) / (passBand(1) - cutoff(1))));
        % Stopband
        desiredResponse(W < cutoff(1)) = 0;
                
            case 'Band Pass Filter'
                % Main passband
                desiredResponse(W >= passBand(1) & W <= passBand(2)) = 1;
                % Lower transition
                trans_low = W < passBand(1) & W >= (passBand(1) - transition_width);
                desiredResponse(trans_low) = 0.5 * (1 - cos(pi * (W(trans_low) - (passBand(1) - transition_width)) / transition_width));
                % Upper transition
                trans_high = W > passBand(2) & W <= (passBand(2) + transition_width);
                desiredResponse(trans_high) = 0.5 * (1 + cos(pi * (W(trans_high) - passBand(2)) / transition_width));
                desiredResponse(W <= passBand(1) & W >= passBand(2)) = 0; % Suppress stopband

            case 'Band Stop Filter'
                % Main stopband
                desiredResponse(W < passBand(1) | W > passBand(2)) = 1;
                % Lower transition
                trans_low = W >= passBand(1) & W < (passBand(1) + transition_width);
                desiredResponse(trans_low) = 0.5 * (1 + cos(pi * (W(trans_low) - passBand(1)) / transition_width));
                % Upper transition
                trans_high = W <= passBand(2) & W > (passBand(2) - transition_width);
                desiredResponse(trans_high) = 0.5 * (1 - cos(pi * (W(trans_high) - (passBand(2) - transition_width)) / transition_width));
                desiredResponse(W >= passBand(1) & W <= passBand(2)) = 0; % Suppress stopband
        end
    end

    function [state, options, optchanged] = gaOutputFunction(app, options, state, flag)
        % Output function to update the UI with each generation
        optchanged = false;

        switch flag
            case 'init'
                disp('GA Initialization...');
                
            case 'iter'
                % Get the best individual from current generation
                [bestScore, idx] = min(state.Score);
                if ~isempty(idx)
                    bestIndividual = state.Population(idx, :);
                    
                    % Update plots for every iteration
                    updatePlots(app, bestIndividual);
                    
                    % Update generation and score labels if they exist
                    app.GenerationLabel.Text = sprintf('Generation: %d', state.Generation);
                    app.BestScoreLabel.Text = sprintf('Best Score: %.2f', bestScore);
                    
                    % Force UI update
                    drawnow;
                    
                    % Brief pause to show evolution
                    pause(0.25);
                end
                
            case 'done'
                % Final update
                [bestScore, idx] = min(state.Score);
                if ~isempty(idx)
                    bestIndividual = state.Population(idx, :);
                    updatePlots(app, bestIndividual);
                    if isfield(app, 'GenerationLabel') && isfield(app, 'BestScoreLabel')
                        app.GenerationLabel.Text = sprintf('Final Generation: %d', state.Generation);
                        app.BestScoreLabel.Text = sprintf('Final Score: %.4f', bestScore);
                    end
                end
                disp('GA Optimization Completed.');
        end
    end

    function updatePlots(app, coeffs)
        % Update the pole-zero plot
        [z, p, ~] = tf2zpk(coeffs, 1);
        
        axes(app.UIAxes_1);
        cla(app.UIAxes_1);
        hold(app.UIAxes_1, 'on');
        
        plot(app.UIAxes_1, real(z), imag(z), 'bo', 'MarkerSize', 8, 'LineWidth', 1.5);
        plot(app.UIAxes_1, real(p), imag(p), 'rx', 'MarkerSize', 8, 'LineWidth', 1.5);
        
        theta = linspace(0, 2*pi, 500);
        plot(app.UIAxes_1, cos(theta), sin(theta), 'k--', 'LineWidth', 1);
        
        axis(app.UIAxes_1, 'equal');
        grid(app.UIAxes_1, 'on');
        title(app.UIAxes_1, 'Pole-Zero Diagram');
        xlabel(app.UIAxes_1, 'Real Part');
        ylabel(app.UIAxes_1, 'Imaginary Part');
        hold(app.UIAxes_1, 'off');
        
        % Update the frequency response plot
        [H, W] = freqz(coeffs, 1, 1024);
        axes(app.UIAxes_2);
        cla(app.UIAxes_2);
        plot(app.UIAxes_2, W/pi, abs(H), 'b', 'LineWidth', 1.5);
        grid(app.UIAxes_2, 'on');
        title(app.UIAxes_2, 'Frequency Response');
        xlabel(app.UIAxes_2, 'Normalized Frequency (\times\pi rad/sample)');
        ylabel(app.UIAxes_2, 'Magnitude');
        ylim(app.UIAxes_2, [0 1.2]);
    end

    function clearPlots(app)
        axes(app.UIAxes_1);
        cla(app.UIAxes_1);
        title(app.UIAxes_1, 'Pole-Zero Diagram');
        xlabel(app.UIAxes_1, 'Real Part');
        ylabel(app.UIAxes_1, 'Imaginary Part');
        
        axes(app.UIAxes_2);
        cla(app.UIAxes_2);
        title(app.UIAxes_2, 'Frequency Response');
        xlabel(app.UIAxes_2, 'Normalized Frequency (\times\pi rad/sample)');
        ylabel(app.UIAxes_2, 'Magnitude');
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
           % Reset displays before starting
    app.GenerationLabel.Text = 'Generation: 0';
    app.BestScoreLabel.Text = 'Best Score: 0';
    
    % Run FIR filter design using GA
    [coeffs, fval] = designFIRFilter(app);
    
    % Update final labels
    app.GenerationLabel.Text = 'Optimization Complete';
    app.BestScoreLabel.Text = sprintf('Final Best Score: %.4f', fval);
    
    % Update plots with final filter design
    updatePlots(app, coeffs);
    
    % Force immediate update of the display
    drawnow;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Clear the pole-zero plot
        axes(app.UIAxes_1);
        cla(app.UIAxes_1);  % Clear current axes
        title(app.UIAxes_1, 'Pole-Zero Diagram');
        xlabel(app.UIAxes_1, 'Real Part');
        ylabel(app.UIAxes_1, 'Imaginary Part');
        
        % Clear the frequency response plot
        axes(app.UIAxes_2);
        cla(app.UIAxes_2);  % Clear current axes
        title(app.UIAxes_2, 'Frequency Response');
        xlabel(app.UIAxes_2, 'Normalized Frequency (\times\pi rad/sample)');
        ylabel(app.UIAxes_2, 'Magnitude');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.FilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDown.Items = {'Band Pass Filter', 'Low Pass Filter', 'High Pass Filter', 'Band Stop Filter'};
            app.FilterTypeDropDown.Value = 'Band Pass Filter';
            app.GeneratePlotButton = uibutton(app.UIFigure, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate Plot';
            app.BestScoreLabel = uilabel(app.UIFigure);
            app.BestScoreLabel.Text = 'BestScore';
            app.PassBandSliderLabel = uilabel(app.UIFigure);
            app.PassBandSliderLabel.Text = 'Pass Band';
            app.PassBandSlider = uislider(app.UIFigure, 'range');
            app.PassBandSlider.Limits = [0 1];
            app.PassBandSlider.Value = [0 1];
            app.CuttoffSliderLabel = uilabel(app.UIFigure);
            app.CuttoffSliderLabel.Text = 'Cutt off';
            app.CutoffSlider = uislider(app.UIFigure, 'range');
            app.CutoffSlider.Limits = [0 1];
            app.CutoffSlider.Value = [0 1];
            app.GenerationLabel = uilabel(app.UIFigure);
            app.GenerationLabel.Text = 'Generation';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FilterOrderEditFieldLabel = uilabel(app.UIFigure);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.FIRFilterDesignerusingGALabel = uilabel(app.UIFigure);
            app.FIRFilterDesignerusingGALabel.Text = 'FIR Filter Designer using GA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FIR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end